classdef DataGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ConnectionDispLabel      matlab.ui.control.Label
        Neuron1Spinner           matlab.ui.control.Spinner
        Neuron2Spinner           matlab.ui.control.Spinner
        Neuron2SpinnerLabel      matlab.ui.control.Label
        Neuron1SpinnerLabel      matlab.ui.control.Label
        PrePostButtonGroup       matlab.ui.container.ButtonGroup
        PreButton                matlab.ui.control.RadioButton
        PostButton               matlab.ui.control.RadioButton
        TestOutput               matlab.ui.control.Label
        SessionStageButtonGroup  matlab.ui.container.ButtonGroup
        RestingStagesLabel       matlab.ui.control.Label
        TaskStagesLabel          matlab.ui.control.Label
        RestCloseButton          matlab.ui.control.RadioButton
        RestOpenButton           matlab.ui.control.RadioButton
        InfoRespButton           matlab.ui.control.RadioButton
        InfoButton               matlab.ui.control.RadioButton
        InfoAntiButton           matlab.ui.control.RadioButton
        DecisionButton           matlab.ui.control.RadioButton
        LoadButton               matlab.ui.control.Button
        LoadingStatusLabel       matlab.ui.control.Label
        SessionIdxDropDown       matlab.ui.control.DropDown
        SessionTypeDropDown      matlab.ui.control.DropDown
        SessionDropDownLabel     matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        ConnectionAxes           matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % constants
        all_session_types = {'Muscimol', 'Saline', 'SimRec'};

        % file path constants/variables
        root_path = '\\storage1.ris.wustl.edu\ilyamonosov\Active\Tianhong\'
        kernel = 'Delta';
        reg = 'L2=2';
        epoch = '2500';

        % environment options
        cmap = NaN;
        colorlim = 2;
    
        % status variables
        file_found = false;
        file_loaded = false;
        conn_plotted = false;

        % option variables
        session_type = '';
        session_idx = '-';
        session_prepost = '';
        session_stage = '';

        current_kernel = 0;

        cursor_x = 0;
        cursor_y = 0;

        % data
        J_mat = NaN;
        N = 0;
        borders = NaN;
        cell_id = NaN;
        cell_area = NaN;
        sort_idx = NaN;
        rasters = NaN;
        spikes = NaN;
    end
    
    methods (Access = private)
        function SyncVariables(app)
            app.session_type = app.SessionTypeDropDown.Value;
            app.session_idx = app.SessionIdxDropDown.Value;
            app.session_prepost = app.PrePostButtonGroup.SelectedObject.Text;
            app.session_stage = app.SessionStageButtonGroup.SelectedObject.Text;
        end

        function UpdateSessionIdxSelection(app)
            session_idx_list = [];
            switch app.session_type
                case 'Muscimol'
                    session_idx_list = 1:10;
                case 'Saline'
                    session_idx_list = 1:5;
                case 'SimRec'
                    session_idx_list = 1:5;
            end

            session_idx_cell = {'-'};
            for i = session_idx_list
                session_idx_cell{end+1} = int2str(i);
            end

            app.SessionIdxDropDown.Items = session_idx_cell;
            app.SessionIdxDropDown.Value = '-';
            app.session_idx = '-';
            app.UpdateSessionStageSelection();
        end

        function UpdateSessionStageSelection(app)
            if strcmp(app.session_idx, '-')
                app.PrePostButtonGroup.Enable = "off";
                app.SessionStageButtonGroup.Enable = "off";
            else
                app.PrePostButtonGroup.Enable = "on";
                app.SessionStageButtonGroup.Enable = "on";
            end
        end

        function UpdateLoadButton(app)
            if strcmp(app.session_idx, '-')
                app.LoadButton.Enable = "off";
                app.LoadingStatusLabel.Text = "Select a Session.";
            else
                % find file
                app.file_found = false;
                if strcmp(app.session_prepost, 'Pre')
                    session_stage_full = [app.session_type, 'Pre', app.session_stage, '_full'];
                else
                    session_stage_full = [app.session_type, 'Post', app.session_stage, '_cortex'];
                end

                file_path = [app.root_path, 'GLM_model/', session_stage_full,...
                    '/GLM_', session_stage_full, '_', app.session_idx, '_',...
                    app.kernel, '_0_', app.reg, '_', app.epoch, '.mat'];
                app.TestOutput.Text = file_path;
                app.file_found = isfile(file_path);

                if app.file_found
                    app.LoadButton.Enable = "on";
                    app.LoadingStatusLabel.Text = "Ready to Load.";
                else
                    app.LoadButton.Enable = "off";
                    app.LoadingStatusLabel.Text = "File Not Found.";
                end
            end
        end

        function PlotConnection(app)
            image = app.J_mat(:, :, app.current_kernel);
            imagesc(app.ConnectionAxes, image);
            clim(app.ConnectionAxes, [-app.colorlim, app.colorlim]);
            colormap(app.ConnectionAxes, app.cmap);
            axis(app.ConnectionAxes, "square");
            xlim(app.ConnectionAxes, [0.5, app.N+0.5]);
            ylim(app.ConnectionAxes, [0.5, app.N+0.5]);
            app.UpdateCursor();

            app.conn_plotted = true;
            app.Neuron1Spinner.Enable = "on";
            app.Neuron2Spinner.Enable = "on";
        end

        function UpdateCursor(app)
            app.cursor_x = app.Neuron1Spinner.Value;
            app.cursor_y = app.Neuron2Spinner.Value;    
            hold(app.ConnectionAxes, "on");
            xmin = app.cursor_x - 0.5;
            ymin = app.cursor_y - 0.5;
            rectangle(app.ConnectionAxes, 'position',[xmin ymin 1 1],'LineWidth',0.1)
            hold(app.ConnectionAxes, "off");
            app.UpdateDisplay();
        end

        function UpdateDisplay(app)
            connection_string = sprintf("%s %s to %s %s, kernel %d, J = %0.3f", ...
                app.cell_area{app.cursor_x}, app.cell_id{app.cursor_x}, ...
                app.cell_area{app.cursor_y}, app.cell_id{app.cursor_y}, ...
                app.current_kernel, app.J_mat(app.cursor_y, app.cursor_x, app.current_kernel));
            app.ConnectionDispLabel.Text = connection_string;


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize session types
            app.SessionTypeDropDown.Items = app.all_session_types;
            app.SessionTypeDropDown.Value = app.all_session_types{1};
            app.session_type = app.all_session_types{1};

            % load colormap
            app.cmap=brewermap(256,'*RdBu');

            % init axes
            axis(app.ConnectionAxes, "square");

            app.UpdateSessionIdxSelection();
            app.UpdateLoadButton();
        end

        % Value changed function: SessionTypeDropDown
        function SessionTypeDropDownValueChanged(app, event)
            app.SyncVariables();
            app.UpdateSessionIdxSelection();
            app.UpdateLoadButton();
        end

        % Value changed function: SessionIdxDropDown
        function SessionIdxDropDownValueChanged(app, event)
            app.SyncVariables();
            app.UpdateSessionStageSelection();
            app.UpdateLoadButton();
        end

        % Selection changed function: PrePostButtonGroup, 
        % ...and 1 other component
        function SessionStageButtonGroupSelectionChanged(app, event)
            app.SyncVariables();
            app.UpdateLoadButton();
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if strcmp(app.session_prepost, 'Pre')
                session_stage_full = [app.session_type, 'Pre', app.session_stage, '_full'];
            else
                session_stage_full = [app.session_type, 'Post', app.session_stage, '_cortex'];
            end

            % load model
            file_path = [app.root_path, 'GLM_model/', session_stage_full,...
                    '/GLM_', session_stage_full, '_', app.session_idx, '_',...
                    app.kernel, '_0_', app.reg, '_', app.epoch, '.mat'];
            load(file_path, "model_par", "PS_kernels", "conn_kernels", "n_PS_kernel", "n_conn_kernel", "kernel_len", "N")
            app.N = N;
            app.J_mat = zeros(N, N, n_conn_kernel);
            
            % load metadata
            load([app.root_path, 'GLM_data/', session_stage_full,'/borders_', session_stage_full, '_', ...
                app.session_idx,'.mat'], "borders");
            app.borders = borders;
            load([app.root_path, 'GLM_data/', session_stage_full,'/sortidx_', session_stage_full, '_', ...
                app.session_idx, '_', app.kernel, '.mat'], "sort_idx");
            app.sort_idx = sort_idx;
            load([app.root_path, 'GLM_data/', session_stage_full,'/raster_',session_stage_full, '_', ...
                app.session_idx,'_0.mat'], "cell_area", "cell_id", "rasters", "spikes");
            app.cell_area = cell_area;
            app.cell_id = cell_id;
            app.rasters = rasters;
            app.spikes = spikes;

            file_path = [app.root_path, 'GLM_model/', session_stage_full,...
                    '/GLM_', session_stage_full, '_', app.session_idx, '_',...
                    app.kernel, '_0_', app.reg, '_', app.epoch, '.mat'];
            load(file_path, "model_par", "PS_kernels", "conn_kernels", "n_PS_kernel", "n_conn_kernel", "kernel_len", "N")
            

            for i = 1:n_conn_kernel
                app.J_mat(:, :, i) = model_par(:, (app.N*(i-1) + n_PS_kernel + 2):(app.N*i + n_PS_kernel + 1));
            end

            app.current_kernel = 1;
            app.Neuron1Spinner.Value = 1;
            app.Neuron2Spinner.Value = 1;
            app.Neuron1Spinner.Limits = [1, app.N];
            app.Neuron2Spinner.Limits = [1, app.N];
            app.PlotConnection();
        end

        % Value changed function: Neuron1Spinner, Neuron2Spinner
        function Neuron1SpinnerValueChanged(app, event)
            app.cursor_x = app.Neuron1Spinner.Value;
            app.cursor_y = app.Neuron2Spinner.Value;
            app.PlotConnection();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectionAxes = uiaxes(app.UIFigure);
            title(app.ConnectionAxes, 'Title')
            app.ConnectionAxes.XTick = [];
            app.ConnectionAxes.YTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raster')
            xlabel(app.UIAxes, 'Time')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [];
            app.SessionDropDownLabel = uilabel(app.UIFigure);
            app.SessionDropDownLabel.Text = 'Session';
            app.SessionTypeDropDown = uidropdown(app.UIFigure);
            app.SessionTypeDropDown.Items = {'Muscimol', 'Saline', 'SimRec'};
            app.SessionTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SessionTypeDropDownValueChanged, true);
            app.SessionTypeDropDown.Value = 'Muscimol';
            app.SessionIdxDropDown = uidropdown(app.UIFigure);
            app.SessionIdxDropDown.Items = {'1'};
            app.SessionIdxDropDown.ValueChangedFcn = createCallbackFcn(app, @SessionIdxDropDownValueChanged, true);
            app.SessionIdxDropDown.Value = '1';
            app.LoadingStatusLabel = uilabel(app.UIFigure);
            app.LoadingStatusLabel.Text = 'Select a Session.';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.SessionStageButtonGroup = uibuttongroup(app.UIFigure);
            app.SessionStageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SessionStageButtonGroupSelectionChanged, true);
            app.SessionStageButtonGroup.Enable = 'off';
            app.SessionStageButtonGroup.Title = 'Stage';
            app.DecisionButton = uiradiobutton(app.SessionStageButtonGroup);
            app.DecisionButton.Text = 'Decision';
            app.DecisionButton.Value = true;
            app.InfoAntiButton = uiradiobutton(app.SessionStageButtonGroup);
            app.InfoAntiButton.Text = 'InfoAnti';
            app.InfoButton = uiradiobutton(app.SessionStageButtonGroup);
            app.InfoButton.Text = 'Info';
            app.InfoRespButton = uiradiobutton(app.SessionStageButtonGroup);
            app.InfoRespButton.Text = 'InfoResp';
            app.RestOpenButton = uiradiobutton(app.SessionStageButtonGroup);
            app.RestOpenButton.Text = 'RestOpen';
            app.RestCloseButton = uiradiobutton(app.SessionStageButtonGroup);
            app.RestCloseButton.Text = 'RestClose';
            app.TaskStagesLabel = uilabel(app.SessionStageButtonGroup);
            app.TaskStagesLabel.Text = 'Task Stages';
            app.RestingStagesLabel = uilabel(app.SessionStageButtonGroup);
            app.RestingStagesLabel.Text = 'Resting Stages';
            app.TestOutput = uilabel(app.UIFigure);
            app.TestOutput.Text = 'Path';
            app.PrePostButtonGroup = uibuttongroup(app.UIFigure);
            app.PrePostButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SessionStageButtonGroupSelectionChanged, true);
            app.PrePostButtonGroup.Enable = 'off';
            app.PrePostButtonGroup.Title = 'Pre/Post';
            app.PostButton = uiradiobutton(app.PrePostButtonGroup);
            app.PostButton.Text = 'Post';
            app.PreButton = uiradiobutton(app.PrePostButtonGroup);
            app.PreButton.Text = 'Pre';
            app.PreButton.Value = true;
            app.Neuron1SpinnerLabel = uilabel(app.UIFigure);
            app.Neuron1SpinnerLabel.Enable = 'off';
            app.Neuron1SpinnerLabel.Text = 'Neuron 1';
            app.Neuron2SpinnerLabel = uilabel(app.UIFigure);
            app.Neuron2SpinnerLabel.Enable = 'off';
            app.Neuron2SpinnerLabel.Text = 'Neuron 2';
            app.Neuron2Spinner = uispinner(app.UIFigure);
            app.Neuron2Spinner.Limits = [1 10];
            app.Neuron2Spinner.ValueChangedFcn = createCallbackFcn(app, @Neuron1SpinnerValueChanged, true);
            app.Neuron2Spinner.Enable = 'off';
            app.Neuron2Spinner.Value = 1;
            app.Neuron1Spinner = uispinner(app.UIFigure);
            app.Neuron1Spinner.Limits = [1 10];
            app.Neuron1Spinner.ValueChangedFcn = createCallbackFcn(app, @Neuron1SpinnerValueChanged, true);
            app.Neuron1Spinner.Enable = 'off';
            app.Neuron1Spinner.Value = 1;
            app.ConnectionDispLabel = uilabel(app.UIFigure);
            app.ConnectionDispLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end